classdef spinhamiltonianplotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        YbYVOtransitionstrengthsUIFigure  matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        RefreshButton              matlab.ui.control.Button
        FieldAngleEditFieldLabel   matlab.ui.control.Label
        FieldAngleEditField        matlab.ui.control.NumericEditField
        BFieldTEditFieldLabel      matlab.ui.control.Label
        BFieldTEditField           matlab.ui.control.EditField
        FreqVectorEditFieldLabel   matlab.ui.control.Label
        FreqVectorEditField        matlab.ui.control.EditField
        PolarizationDropDownLabel  matlab.ui.control.Label
        PolarizationDropDown       matlab.ui.control.DropDown
        Yb171CheckBox              matlab.ui.control.CheckBox
        ZeroSpinCheckBox           matlab.ui.control.CheckBox
        PlottoNewButton            matlab.ui.control.Button
        PlotTypeLabel              matlab.ui.control.Label
        PlottypeDropDown           matlab.ui.control.DropDown
        InvertColorCheckBox        matlab.ui.control.CheckBox
        Yb173CheckBox              matlab.ui.control.CheckBox
    end


    properties (Access = private)
        mattoplot = []; % Description
        plotaxes = [];
    end

    methods (Access = private)
    
        function refreshPlot(app)
            app.plotaxes = app.UIAxes;
            switch str2num(app.PlottypeDropDown.Value)
                case 1
                    app.plottransitionstrengths;
                case 2
                    app.plottransitionsGS('-');
                case 3
                    app.plottransitionsES('-');
                case 4
                    app.plottransitionstrengths
                    hold(app.plotaxes,'on')
                    app.plottransitionsGS('--')
                    hold(app.plotaxes,'off')
                case 5
                    app.plottransitionstrengths
                    hold(app.plotaxes,'on')
                    app.plottransitionsES('--')
                    hold(app.plotaxes,'off')
                    
            end

        end


        function plottransitionsGS(app,marker)
            ybyvo = yb171yvospinhamiltonian;
            ybyvo0spin = yb170yvospinhamiltonian;
            bvec = str2num(app.BFieldTEditField.Value);
            anglewrtc = app.FieldAngleEditField.Value;
            x = str2num(app.FreqVectorEditField.Value);
            
            %% plot different stuff based on choice in front. 
            ybyvo.step_magnetic_field_amplitude(bvec,anglewrtc*pi/180,0)
            ybyvo0spin.step_magnetic_field_amplitude(bvec,anglewrtc*pi/180,0)
            
            ybyvo.find_transitions;
            ybyvo0spin.find_transitions;
            
            
            %if app.ZeroSpinCheckBox.Value && app.Yb171CheckBox.Value
            %    app.mattoplot = app.mattoplot-1;
            %    app.mattoplot(app.mattoplot<0) = 0;
            %end
            
            app.mattoplot = ybyvo.transitions;


            p = plot(app.plotaxes,bvec*1000, app.mattoplot,marker);
            c = lines;
            n_es = 4;
            n_gs = 4;
            for exclevel = 1:n_es
                for gndlevel = 1:n_gs
                    p(n_gs*(exclevel-1) + gndlevel).Color  = c(gndlevel,:);
                end
            end


            xlabel(app.plotaxes,'B (mT)')
            ylabel(app.plotaxes,'Detuning (GHz)')
            xlim(app.plotaxes,[0 max(bvec)*1000])
            ylim(app.plotaxes,[x(1) x(end)])
            
        end
    
        function plottransitionsES(app,marker)
            ybyvo = yb171yvospinhamiltonian;
            ybyvo0spin = yb170yvospinhamiltonian;
            bvec = str2num(app.BFieldTEditField.Value);
            anglewrtc = app.FieldAngleEditField.Value;
            x = str2num(app.FreqVectorEditField.Value);
            
            %% plot different stuff based on choice in front. 
            ybyvo.step_magnetic_field_amplitude(bvec,anglewrtc*pi/180,0)
            ybyvo0spin.step_magnetic_field_amplitude(bvec,anglewrtc*pi/180,0)
            
            ybyvo.find_transitions;
            ybyvo0spin.find_transitions;
            
            
            %if app.ZeroSpinCheckBox.Value && app.Yb171CheckBox.Value
            %    app.mattoplot = app.mattoplot-1;
            %    app.mattoplot(app.mattoplot<0) = 0;
            %end
            
            app.mattoplot = ybyvo.transitions;

            p = plot(app.plotaxes,bvec*1000, app.mattoplot,marker);
            c = lines;
            n_es = 4;
            n_gs = 4;
            for exclevel = 1:n_es
                for gndlevel = 1:n_gs
                    p(n_gs*(exclevel-1) + gndlevel).Color  = c(exclevel,:);
                end
            end


            xlabel(app.plotaxes,'B (mT)')
            ylabel(app.plotaxes,'Detuning (GHz)')
            xlim(app.plotaxes,[0 max(bvec)*1000])
            ylim(app.plotaxes,[x(1) x(end)])
        end
  
  
        function plottransitionstrengths(app)
            ybyvo = yb171yvospinhamiltonian;
            ybyvo0spin = yb170yvospinhamiltonian;
            ybyvo173 = yb173yvospinhamiltonian;
            bvec = str2num(app.BFieldTEditField.Value);
            anglewrtc = app.FieldAngleEditField.Value;
            x = str2num(app.FreqVectorEditField.Value);
            
            %% plot different stuff based on choice in front. 
            ybyvo.find_transition_strengths_amplitude(bvec,anglewrtc*pi/180,0)
            ybyvo0spin.find_transition_strengths_amplitude(bvec,anglewrtc*pi/180,0)
            ybyvo173.find_transition_strengths_amplitude(bvec,anglewrtc*pi/180,0)
            widths = 0.10*ones(length(ybyvo.transition_strengths_par(1,:)),1);
            widths0 = 0.10*ones(length(ybyvo0spin.transition_strengths_par(1,:)),1);
            widths173 = 0.10*ones(length(ybyvo173.transition_strengths_par(1,:)),1);
            
            parmat = zeros(length(bvec),length(x));
            perpxmat = zeros(length(bvec),length(x));
            perpymat = zeros(length(bvec),length(x));
            for it = 1:length(bvec)
                if app.Yb171CheckBox.Value
                    parmat(it,:) = exp(-5*(lorentzian_multiple( x, ybyvo.transitions(it,:), widths, ybyvo.transition_strengths_par(it,:))));
                    perpxmat(it,:) = exp(-5*(lorentzian_multiple( x, ybyvo.transitions(it,:), widths, ybyvo.transition_strengths_perpx(it,:))));
                    perpymat(it,:) = exp(-5*(lorentzian_multiple( x, ybyvo.transitions(it,:), widths, ybyvo.transition_strengths_perpy(it,:))));
                end
                
                if app.ZeroSpinCheckBox.Value
                    parmat(it,:) = parmat(it,:) + exp(-5*(lorentzian_multiple( x, ybyvo0spin.transitions(it,:), widths0, ybyvo0spin.transition_strengths_par(it,:))));
                    perpxmat(it,:) = perpxmat(it,:) + exp(-5*(lorentzian_multiple( x, ybyvo0spin.transitions(it,:), widths0, ybyvo0spin.transition_strengths_perpx(it,:))));
                    perpymat(it,:) = perpymat(it,:) + exp(-5*(lorentzian_multiple( x, ybyvo0spin.transitions(it,:), widths0, ybyvo0spin.transition_strengths_perpy(it,:))));
                end
                
                
                if app.Yb173CheckBox.Value
                    parmat(it,:) = parmat(it,:) + exp(-5*(lorentzian_multiple( x, ybyvo173.transitions(it,:), widths173, ybyvo173.transition_strengths_par(it,:))));
                    perpxmat(it,:) = perpxmat(it,:) + exp(-5*(lorentzian_multiple( x, ybyvo173.transitions(it,:), widths173, ybyvo173.transition_strengths_perpx(it,:))));
                    perpymat(it,:) = perpymat(it,:) + exp(-5*(lorentzian_multiple( x, ybyvo173.transitions(it,:), widths173, ybyvo173.transition_strengths_perpy(it,:))));
                end
                

                %parmat0spin(it,:) = exp(-5*(lorentzian_multiple( x, ybyvo0spin.transitions(it,:), widths, ybyvo0spin.transition_strengths_par(it,:))));
                %perpmat(it,:) = exp(-5*(lorentzian_multiple( x, ybyvo.transitions(it,:), widths, 0.2*heights)));
            end

            switch str2num(app.PolarizationDropDown.Value)
                case 1
                    app.mattoplot = parmat';
                case 2
                    app.mattoplot = perpxmat';
                case 3
                    app.mattoplot = perpymat';
            end
            
            if app.ZeroSpinCheckBox.Value && app.Yb171CheckBox.Value
                app.mattoplot = app.mattoplot-1;
                app.mattoplot(app.mattoplot<0) = 0;
            end
            
            if app.ZeroSpinCheckBox.Value && app.Yb173CheckBox.Value
                app.mattoplot = app.mattoplot-1;
                app.mattoplot(app.mattoplot<0) = 0;
            end

            imagesc(app.plotaxes,bvec*1000, x, app.mattoplot)
            set(app.plotaxes,'YDir','normal')
            c = bone;
            if app.InvertColorCheckBox.Value
                c = flipud(c);
            end
            colormap(app.plotaxes,c);
            view(app.plotaxes,[0,90])


            xlabel(app.plotaxes,'B (mT)')
            ylabel(app.plotaxes,'Detuning (GHz)')
            xlim(app.plotaxes,[0 max(bvec)*1000])
            ylim(app.plotaxes,[x(1) x(end)])
            
        end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.YbYVOtransitionstrengthsUIFigure.Color = [1 1 1];
            app.refreshPlot
        end

        % Callback function: BFieldTEditField, FieldAngleEditField, 
        % FreqVectorEditField, InvertColorCheckBox, PlottypeDropDown, 
        % PolarizationDropDown, RefreshButton, Yb171CheckBox, 
        % Yb173CheckBox, ZeroSpinCheckBox
        function PlotButtonPushed(app, event)
            app.refreshPlot
            
        end

        % Button pushed function: PlottoNewButton
        function PlottoNewButtonPushed(app, event)
            bvec = str2num(app.BFieldTEditField.Value);
            anglewrtc = app.FieldAngleEditField.Value;
            x = str2num(app.FreqVectorEditField.Value);
            
            pol = str2num(app.PolarizationDropDown.Value);
   
            figname = sprintf('B at %2.1f deg with %s',anglewrtc,app.PolarizationDropDown.Items{pol});%app.PolarizationDropDown.Value
            plottitle = sprintf('B at %2.1f^\\circ with %s',anglewrtc,app.PolarizationDropDown.Items{pol});
            figure('name',figname);            
            app.plotaxes = axes;
            
            switch str2num(app.PlottypeDropDown.Value)
                case 1
                    app.plottransitionstrengths;
                case 2
                    app.plottransitionsGS('-');
                case 3
                    app.plottransitionsES('-');
                case 4
                    app.plottransitionstrengths
                    hold(app.plotaxes,'on')
                    app.plottransitionsGS('--')
                    hold(app.plotaxes,'off')
                case 5
                    app.plottransitionstrengths
                    hold(app.plotaxes,'on')
                    app.plottransitionsES('--')
                    hold(app.plotaxes,'off')
            end
            
            title(app.plotaxes,plottitle)        


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.YbYVOtransitionstrengthsUIFigure = uifigure;
            app.YbYVOtransitionstrengthsUIFigure.Name = 'Yb:YVO transition strengths';
            app.UIAxes = uiaxes(app.YbYVOtransitionstrengthsUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RefreshButton = uibutton(app.YbYVOtransitionstrengthsUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.FieldAngleEditFieldLabel = uilabel(app.YbYVOtransitionstrengthsUIFigure);
            app.FieldAngleEditFieldLabel.Text = 'Field Angle ';
            app.FieldAngleEditField = uieditfield(app.YbYVOtransitionstrengthsUIFigure, 'numeric');
            app.FieldAngleEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.BFieldTEditFieldLabel = uilabel(app.YbYVOtransitionstrengthsUIFigure);
            app.BFieldTEditFieldLabel.Text = 'B Field (T)';
            app.BFieldTEditField = uieditfield(app.YbYVOtransitionstrengthsUIFigure, 'text');
            app.BFieldTEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.BFieldTEditField.Value = '0:0.001:0.1';
            app.FreqVectorEditFieldLabel = uilabel(app.YbYVOtransitionstrengthsUIFigure);
            app.FreqVectorEditFieldLabel.Text = 'Freq. Vector';
            app.FreqVectorEditField = uieditfield(app.YbYVOtransitionstrengthsUIFigure, 'text');
            app.FreqVectorEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.FreqVectorEditField.Value = '-10:0.01:10';
            app.PolarizationDropDownLabel = uilabel(app.YbYVOtransitionstrengthsUIFigure);
            app.PolarizationDropDownLabel.Text = 'Polarization';
            app.PolarizationDropDown = uidropdown(app.YbYVOtransitionstrengthsUIFigure);
            app.PolarizationDropDown.Items = {'E || c', 'E || a, x', 'E || a, y'};
            app.PolarizationDropDown.ItemsData = {'1', '2', '3'};
            app.PolarizationDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PolarizationDropDown.Value = '1';
            app.Yb171CheckBox = uicheckbox(app.YbYVOtransitionstrengthsUIFigure);
            app.Yb171CheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Yb171CheckBox.Text = 'Yb171';
            app.Yb171CheckBox.Value = true;
            app.ZeroSpinCheckBox = uicheckbox(app.YbYVOtransitionstrengthsUIFigure);
            app.ZeroSpinCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.ZeroSpinCheckBox.Text = 'Zero Spin';
            app.PlottoNewButton = uibutton(app.YbYVOtransitionstrengthsUIFigure, 'push');
            app.PlottoNewButton.ButtonPushedFcn = createCallbackFcn(app, @PlottoNewButtonPushed, true);
            app.PlottoNewButton.Text = 'Plot to New';
            app.PlotTypeLabel = uilabel(app.YbYVOtransitionstrengthsUIFigure);
            app.PlotTypeLabel.Text = 'Plot Type';
            app.PlottypeDropDown = uidropdown(app.YbYVOtransitionstrengthsUIFigure);
            app.PlottypeDropDown.Items = {'Strengths', 'Shared Ground State', 'Shared Excited State', 'Overlay Shared Ground', 'Overlay Shared Excited'};
            app.PlottypeDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.PlottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlottypeDropDown.Value = '1';
            app.InvertColorCheckBox = uicheckbox(app.YbYVOtransitionstrengthsUIFigure);
            app.InvertColorCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.InvertColorCheckBox.Text = 'Invert Color';
            app.Yb173CheckBox = uicheckbox(app.YbYVOtransitionstrengthsUIFigure);
            app.Yb173CheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Yb173CheckBox.Text = 'Yb173';
        end
    end

    methods (Access = public)

        % Construct app
        function app = spinhamiltonianplotter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.YbYVOtransitionstrengthsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.YbYVOtransitionstrengthsUIFigure)
        end
    end
end